classdef FKin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JointAnglesTextArea            matlab.ui.control.TextArea
        JointAnglesTextAreaLabel       matlab.ui.control.Label
        YMinusButton                   matlab.ui.control.Button
        YPlusButton                    matlab.ui.control.Button
        XMinusButton                   matlab.ui.control.Button
        XPlusButton                    matlab.ui.control.Button
        EndEffectoryPositionEditField  matlab.ui.control.NumericEditField
        EndEffectoryPositionEditFieldLabel  matlab.ui.control.Label
        CreateRobotButton              matlab.ui.control.Button
        EndEffectorxPositionEditField  matlab.ui.control.NumericEditField
        EndEffectorxPositionEditFieldLabel  matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = DrawBot; % This is a Serial Link Created robot 
        jointangles= [0 0]; % these are the joint angles of the robot 
        arrowIncrement = 0.2;
    end
    
    methods (Access = private)
        
        
        function UpdateAngles(app)
            app.Joint1Slider.Value = app.jointangles(1);
            app.Joint2Slider.Value = app.jointangles(2);
            app.JointAnglesTextArea.Value = string([app.jointangles(1), string(app.jointangles(2))]);
            app.robot.plot(app.jointangles * pi/180);  
        end

        function UpdateXY(app)
            %calculate the forward kinematics
            T = app.robot. fkine([app.jointangles(1)*pi/180, app.jointangles(2)*pi/180]);%transformation matrix
            T = T. T;%changes obj type to standard numerical matrix
            %Update the position values of the end-effector
            app.EndEffectorxPositionEditField.Value = T(1,4);%update the x value of the end effector
            app.EndEffectoryPositionEditField.Value = T(2,4) ;%update the y value of end effector
            app.robot.plot(app.jointangles * pi/180)
        end

        function MoveTo(app, xy)
            angles = app.robot.ikine(transl(xy(1), xy(2), 0),'mask', [1 1 0 0 0 0]);
            app.jointangles = (180/pi) * angles;
            app.UpdateAngles()
            app.UpdateXY()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderChanged(app, event)
            app.jointangles (1) = app.Joint1Slider.Value;
            app.UpdateAngles()
            app.UpdateXY()
        end

        % Value changed function: Joint2Slider
        function Joint2SliderChanged(app, event)
            app.jointangles (2) = app.Joint2Slider.Value;
            app.UpdateAngles()
            app.UpdateXY()
        end

        % Value changed function: EndEffectorxPositionEditField
        function EndEffectorXPosChanged(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value, app.EndEffectoryPositionEditField.Value]);
        end

        % Button pushed function: CreateRobotButton
        function Create_Callback(app, event)
            app.robot.plot(app.jointangles * 1)
            axis([-10, 25, -5, 35, 0, 15])
            view(30, 45)

            app.UpdateAngles()
            app.UpdateXY()
        end

        % Value changed function: EndEffectoryPositionEditField
        function endEffectorYPosChanged(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value, app.EndEffectoryPositionEditField.Value]);
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value - app.arrowIncrement, app.EndEffectoryPositionEditField.Value])
        end

        % Button pushed function: XPlusButton
        function XPlusButtonPushed(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value + app.arrowIncrement, app.EndEffectoryPositionEditField.Value])
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value, app.EndEffectoryPositionEditField.Value - app.arrowIncrement])
        end

        % Button pushed function: YPlusButton
        function YPlusButtonPushed(app, event)
            MoveTo(app, [app.EndEffectorxPositionEditField.Value, app.EndEffectoryPositionEditField.Value + app.arrowIncrement])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'UIFigure';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-25 165];
            app.Joint1Slider.MajorTicks = [-25 0 45 90 135 165];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-160 60];
            app.Joint2Slider.MajorTicks = [-160 -90 -45 0 45 60];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderChanged, true);
            app.EndEffectorxPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectorxPositionEditFieldLabel.Text = 'End Effector x-Position';
            app.EndEffectorxPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectorxPositionEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorXPosChanged, true);
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.EndEffectoryPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectoryPositionEditFieldLabel.Text = 'End Effector y-Position';
            app.EndEffectoryPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectoryPositionEditField.ValueChangedFcn = createCallbackFcn(app, @endEffectorYPosChanged, true);
            app.XPlusButton = uibutton(app.UIFigure, 'push');
            app.XPlusButton.ButtonPushedFcn = createCallbackFcn(app, @XPlusButtonPushed, true);
            app.XPlusButton.Text = '+';
            app.XMinusButton = uibutton(app.UIFigure, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Text = '-';
            app.YPlusButton = uibutton(app.UIFigure, 'push');
            app.YPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YPlusButtonPushed, true);
            app.YPlusButton.Text = '+';
            app.YMinusButton = uibutton(app.UIFigure, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Text = '-';
            app.JointAnglesTextAreaLabel = uilabel(app.UIFigure);
            app.JointAnglesTextAreaLabel.Text = 'Joint Angles';
            app.JointAnglesTextArea = uitextarea(app.UIFigure);
            app.JointAnglesTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FKin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end